classdef nCORTEx_console < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        ExpmntDropDown                  matlab.ui.control.DropDown
        Switch2                         matlab.ui.control.Switch
        StatusBar                       slrealtime.ui.control.StatusBar
        SelectSubjectDropDown           matlab.ui.control.DropDown
        SessionTimerminEditField        matlab.ui.control.NumericEditField
        connectTarget                   matlab.ui.control.StateButton
        LoggingLabel                    matlab.ui.control.Label
        SelectProjectDirectoryDropDown  matlab.ui.control.DropDown
        SaveButton                      matlab.ui.control.Button
        DataStreamingModalitiesLabel    matlab.ui.control.Label
        PiezoPanel                      matlab.ui.container.Panel
        Switch_15                       matlab.ui.control.Switch
        Switch2Label                    matlab.ui.control.Label
        OptogeneticsPanel               matlab.ui.container.Panel
        LRLabel_7                       matlab.ui.control.Label
        Switch3                         matlab.ui.control.Switch
        WavelengthDropDown_6            matlab.ui.control.DropDown
        WavelengthDropDown_6Label       matlab.ui.control.Label
        StimPatternLabel                matlab.ui.control.Label
        WavelengthLabel                 matlab.ui.control.Label
        OpsinLabel                      matlab.ui.control.Label
        StimPatternDropDown             matlab.ui.control.DropDown
        StimPatternDropDownLabel        matlab.ui.control.Label
        OpsinDropDown                   matlab.ui.control.DropDown
        OpsinDropDownLabel              matlab.ui.control.Label
        FiberLocationLabel_2            matlab.ui.control.Label
        Fiber1DropDown_2                matlab.ui.control.DropDown
        Fiber1DropDown_2Label           matlab.ui.control.Label
        Switch_10                       matlab.ui.control.Switch
        LRLabel_3                       matlab.ui.control.Label
        SessionMetricsPanel             matlab.ui.container.Panel
        HitRateLabel                    matlab.ui.control.Label
        HitRate                         matlab.ui.control.Label
        ValidateButton                  matlab.ui.control.Button
        PupilFrmCount                   matlab.ui.control.Label
        WhiskFrmCount                   matlab.ui.control.Label
        WhiskLabel                      matlab.ui.control.Label
        PupilLabel                      matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PupilTrigTab                    matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        WhiskerTrigTab                  matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        NpxlsRefTab                     matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        Tab4                            matlab.ui.container.Tab
        UIAxes_WhiskFrames              matlab.ui.control.UIAxes
        UIAxes_PupilFrames              matlab.ui.control.UIAxes
        SelectSubjectDropDownLabel      matlab.ui.control.Label
        SessionTimerminEditFieldLabel   matlab.ui.control.Label
        VisionPanel                     matlab.ui.container.Panel
        SpinviewButton                  matlab.ui.control.Button
        WhiskerSNLabel                  matlab.ui.control.Label
        PupilSNLabel                    matlab.ui.control.Label
        WhiskFSEditField                matlab.ui.control.NumericEditField
        WhiskFSEditFieldLabel           matlab.ui.control.Label
        WhiskerSNDropDown               matlab.ui.control.DropDown
        WhiskerSNLabel_2                matlab.ui.control.Label
        PupilFSEditField                matlab.ui.control.NumericEditField
        PupilFSEditField_2Label         matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        PupilSNDropDown                 matlab.ui.control.DropDown
        PupilSNLabel_2                  matlab.ui.control.Label
        PharmacologyPanel               matlab.ui.container.Panel
        TimefromDelivEditField          matlab.ui.control.NumericEditField
        TimefromDelivEditFieldLabel     matlab.ui.control.Label
        DosageDropDown                  matlab.ui.control.DropDown
        DosageDropDownLabel             matlab.ui.control.Label
        DrugDropDown                    matlab.ui.control.DropDown
        DrugDropDownLabel               matlab.ui.control.Label
        Switch_14                       matlab.ui.control.Switch
        PhotometryPanel                 matlab.ui.container.Panel
        LRLabel_8                       matlab.ui.control.Label
        Switch4                         matlab.ui.control.Switch
        Switch4Label                    matlab.ui.control.Label
        WavelengthDropDown              matlab.ui.control.DropDown
        WavelengthDropDownLabel         matlab.ui.control.Label
        FluorophoreDropDown             matlab.ui.control.DropDown
        FluorophoreDropDownLabel        matlab.ui.control.Label
        FiberLocationLabel              matlab.ui.control.Label
        Fiber1DropDown                  matlab.ui.control.DropDown
        Fiber1DropDownLabel             matlab.ui.control.Label
        Switch_3                        matlab.ui.control.Switch
        SelectProjectDirectoryDropDownLabel  matlab.ui.control.Label
        NeuropixelsPanel                matlab.ui.container.Panel
        LRLabel_2                       matlab.ui.control.Label
        LRLabel                         matlab.ui.control.Label
        Switch_6                        matlab.ui.control.Switch
        Switch_6Label                   matlab.ui.control.Label
        Switch_5                        matlab.ui.control.Switch
        Switch_5Label                   matlab.ui.control.Label
        Npxls1DropDown_3                matlab.ui.control.DropDown
        Npxls1DropDown_3Label           matlab.ui.control.Label
        N2LocationLabel                 matlab.ui.control.Label
        N1LocationLabel                 matlab.ui.control.Label
        Npxls2DropDown                  matlab.ui.control.DropDown
        Npxls2DropDownLabel             matlab.ui.control.Label
        Npxls_FSEditField               matlab.ui.control.NumericEditField
        RefFSLabel                      matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        ExpmntDropDownLabel             matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        slTarget=slrealtime; % Description
        isStopped % Description
        sampleTime=0.001 % Description   
        params=setBaseParams(struct);
        sessionParams=[];
    end
    
    methods (Access = private)
        
        function setSimulinkParams(app)
            T_npxls = (1/app.Npxls_FSEditField.Value)/(app.sampleTime);
            T_pupil = (1/app.PupilFSEditField.Value)/(app.sampleTime);
            T_whisk = (1/app.WhiskFSEditField.Value)/(app.sampleTime);
            load_system('nCORTEx');
            mdlWks = get_param('nCORTEx','ModelWorkspace');
            assignin(mdlWks, 'tStop', (app.SessionTimerminEditField.Value*60)); % minute to second conversion
            assignin(mdlWks, 'T_npxls', T_npxls);
            assignin(mdlWks, 'T_pupil', T_pupil);
            assignin(mdlWks, 'T_whisk', T_whisk);
            assignin(mdlWks, 'toneDuration', 1.5);
            assignin(mdlWks, 'maxFrames', (app.SessionTimerminEditField.Value*60)/app.sampleTime);
            % set_param('nCORTEx','ModelWorkspace',mdlWks);
            % set_param('nCORTEx','StopTime',app.SessionTimerminEditField.Value)
            % set_param('nCORTEx'...  
            %     , 'tStop', app.SessionTimerminEditField.Value
            %     , 'T_npxls', T_npxls ...
            %     , 'T_pupil', T_pupil ...
            %     , 'T_whisk', T_whisk ...
            %     , 'toneDuration', 1.5);
        end
        
        function pyDict = struct2dict(app, matStruct)
            % Returns a matlab struct as a text formatted python dictionary
            % containing struct fields as dict keys and struct values as dict values
            % Converts a MATLAB struct to a text-formatted Python dictionary
    
            % Start with an empty string
            pyDict = '{';
            
            % Loop through the fields of the struct
            fields = fieldnames(matStruct);
            for i = 1:length(fields)
                field = fields{i};
                value = matStruct.(field);
                
                % Convert the field name and value to strings
                fieldStr = sprintf('"%s"', field);
                valueStr = '';
                
                % Check the data type of the value and format it accordingly
                if ischar(value)
                    valueStr = sprintf('"%s"', value);
                elseif isnumeric(value) || islogical(value)
                    valueStr = num2str(value);
                elseif isstruct(value)
                    % Recursively convert nested structs
                    valueStr = struct2dict(app, value);
                else
                    % Handle other data types as needed
                    error('Unsupported data type in struct.');
                end
                
                % Combine the field and value strings
                pairStr = sprintf('%s: %s', fieldStr, valueStr);
                
                % Add the pair to the dictionary string
                if i < length(fields)
                    pyDict = [pyDict, pairStr, ', '];
                else
                    pyDict = [pyDict, pairStr];
                end
            end
            
            % Close the dictionary
            pyDict = [pyDict, '}'];
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.isStopped=0;
            % empty Temp folder
            % - code here to empty
            app.PupilLabel.FontColor=[0.18,0.18,0.22];
            app.WhiskLabel.FontColor=[0.18,0.18,0.22];

            app.params.spinParams.execStatus='start';
            % re-update fps values based on GUI            
            % app.params.spinParams.saveDir=app.SelectProjectDirectoryDropDownLabel.Text;
            app.params.spinParams.saveDir=app.params.paths.tempDir;
            

            %% Build and Load model
            tg = app.slTarget;
            disp(strcat('Accessing', {' '},tg.TargetSettings.name,{'...'}));
            % set Sim Pameters
            setSimulinkParams(app);
            slbuild('nCORTEx');                       
            load(tg,'nCORTEx');                                  

            app.slTarget=tg;

            %% Initiate processes             
            
            % Use parfeval to run the Python scripts concurrently
            app.params.spinParams.camSelect=app.params.spinParams.pupilSN;
            spinParams0 = struct2dict(app, app.params.spinParams);
            app.params.spinParams.camSelect=app.params.spinParams.whiskSN;
            spinParams1 = struct2dict(app, app.params.spinParams);
            spinParamsJSON0 = jsonencode(spinParams0);
            spinParamsJSON1 = jsonencode(spinParams1);
                
            system(strjoin(["parallel './callSpinTEx.sh' ::: " spinParamsJSON0 ' ' spinParamsJSON1 ' &']));
            pause(5)


            % save session params
            app.sessionParams = app.params;
            app.sessionParams.pupilFS=app.PupilFSEditField.Value;
            app.sessionParams.whiskFS=app.WhiskFSEditField.Value;
            % start session
            start(app.slTarget)            
            
            % Draw a progress bar            
            sessionTime = app.SessionTimerminEditField.Value*60; % minute to second conversion
            cla(app.UIAxes)     
            cla(app.UIAxes_PupilFrames)
            cla(app.UIAxes_WhiskFrames)
            while true
                % filledWidth=i;
                % log = getlogsout()
                widthPrct = (app.slTarget.ModelStatus.ExecTime/sessionTime); 
                
                numPupilFrames = countFrames(fullfile(app.params.paths.tempDir,"Raw Pupil Frames"));
                totPupilFrames = app.sessionParams.pupilFS * sessionTime;
                pupPrct = numPupilFrames/totPupilFrames;                               
                
                numWhiskFrames = countFrames(fullfile(app.params.paths.tempDir, "Raw Whisker Frames"));
                totWhiskFrames = app.sessionParams.whiskFS * sessionTime;
                wskPrct = numWhiskFrames/totWhiskFrames;

                app.PupilFrmCount.Text=string(numPupilFrames);
                app.WhiskFrmCount.Text=string(numWhiskFrames);
                switch(app.params.hostName)
                    case 'electro'
                        drawrectangle(app.UIAxes,'Position', [0, 0,widthPrct, 1],'InteractionsAllowed','none','Color',[0.24,0.94,0.46]);
                        if pupPrct > 0
                            drawrectangle(app.UIAxes_PupilFrames,'Position', [0, 0,1, pupPrct],'InteractionsAllowed','none','Color',[0.24,0.94,0.46]);
                        end
                        if wskPrct > 0
                            drawrectangle(app.UIAxes_WhiskFrames,'Position', [0, 0,1, wskPrct],'InteractionsAllowed','none','Color',[0.24,0.94,0.46]);
                        end
                    case 'genoma'
                        drawrectangle(app.UIAxes,'Position', [0, 0,widthPrct, 1],'InteractionsAllowed','none','Color',[1.00,0.33,0.42]);
                        if pupPrct > 0
                            drawrectangle(app.UIAxes_PupilFrames,'Position', [0, 0,1, pupPrct],'InteractionsAllowed','none','Color',[1.00,0.33,0.42]);
                        end
                        if wskPrct > 0
                            drawrectangle(app.UIAxes_WhiskFrames,'Position', [0, 0,1, wskPrct],'InteractionsAllowed','none','Color',[1.00,0.33,0.42]);
                        end
                    otherwise
                        drawrectangle(app.UIAxes,'Position', [0, 0,widthPrct, 1],'InteractionsAllowed','none','Color',[0.83,0.62,0.16]);
                end
                drawnow;
                if app.isStopped
                    % If model completes without user's stop input, stop camera acquisition            
                    app.params.spinParams.execStatus='stop';
                    spinParams = struct2dict(app, app.params.spinParams); 
                    spinParamsJSON = jsonencode(spinParams);
                    disp("stopping slx")
                    stop(app.slTarget)
                    disp(strcat('Application',{' '},'Stopped'))
                    % Release resources
                    system(strjoin(["./callSpinTEx.sh" '' spinParamsJSON ' &']))          
                    break;
                elseif string(app.slTarget.ModelStatus.State)=="DONE"
                    % If model completes without user's stop input, stop camera acquisition            
                    app.params.spinParams.execStatus='stop';
                    spinParams = struct2dict(app, app.params.spinParams); 
                    spinParamsJSON = jsonencode(spinParams);
                    disp("stopping slx")
                    stop(app.slTarget)
                    disp(strcat('Application',{' '},'Stopped'))
                    % Release resources
                    system(strjoin(["./callSpinTEx.sh" '' spinParamsJSON ' &']))          
                    % system("pkill python")
                    break;
                end                               
            end     
            % final dir count
            numPupilFrames = countFrames(fullfile(app.params.paths.tempDir,"Raw Pupil Frames"));                                                          
            numWhiskFrames = countFrames(fullfile(app.params.paths.tempDir, "Raw Whisker Frames"));                
            app.PupilFrmCount.Text=string(numPupilFrames);
            app.WhiskFrmCount.Text=string(numWhiskFrames);
            if numPupilFrames == app.sessionParams.pupilFS*round(sessionTime)
                app.PupilLabel.FontColor=[0.24,0.94,0.46];
            else
                app.PupilLabel.FontColor=[1.00,0.41,0.16];
            end
            if numWhiskFrames == app.sessionParams.whiskFS*round(sessionTime)
                app.WhiskLabel.FontColor=[0.24,0.94,0.46];
            else
                app.WhiskLabel.FontColor=[1.00,0.41,0.16];
            end
            
            % % If model completes without user's stop input, stop camera acquisition            
            % app.params.spinParams.execStatus='stop';
            % spinParams = struct2dict(app, app.params.spinParams); 
            % spinParamsJSON = jsonencode(spinParams);
            % disp("stopping slx")
            % stop(app.slTarget)
            % disp(strcat('Application',{' '},'Stopped'))

            % system(strjoin(["./callSpinTEx.sh" '' spinParamsJSON '']))          
            

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.isStopped=1;
            % tg = app.slTarget;                        
            % stop(app.slTarget);            
            % app.slTarget=tg;
            % disp(strcat('Application',{' '},'Stopped'))
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            savePath = app.SelectProjectDirectoryDropDownLabel.Text;
            disp(['saving log data to: ',savePath]);
            logData = evalin('base','logsout');
            save(fullfile(app.SelectProjectDirectoryDropDownLabel.Text,"log.mat"),'logData')
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'On'
                    app.VisionPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.VisionPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            switch value
                case 'On'
                    app.NeuropixelsPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.NeuropixelsPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            switch value
                case 'On'
                    app.PhotometryPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.PhotometryPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            
        end

        % Callback function
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            switch value
                case 'On'
                    app.PiezoPanel_X.ForegroundColor=[1,1,1];
                case 'Off'
                    app.PiezoPanel_X.ForegroundColor=[0.36,0.36,0.36];
            end
            
        end

        % Value changed function: connectTarget
        function connectTargetValueChanged(app, event)
            value = app.connectTarget.Value;
            switch value
                case 1
                    tg = app.slTarget;
                    disp('connecting...')
                    tg.connect;                    
                    disp(strcat(tg.TargetSettings.name,{' '},'connected'))
                    app.connectTarget.Icon='slrtConnectIcon.png';
                    app.slTarget=tg;
                case 0
                    tg = app.slTarget;
                    disp('disconnecting...')
                    tg.disconnect;                    
                    disp(strcat(tg.TargetSettings.name,{' '},'disconnected'))
                    app.connectTarget.Icon='slrtDisconnectIcon.png';
                    app.slTarget=tg;
            end
        end

        % Value changed function: SelectProjectDirectoryDropDown
        function SelectProjectDirectoryDropDownValueChanged(app, event)
            value = app.SelectProjectDirectoryDropDown.Value;            
            switch value
                case 'Neuromodulation for Pain'
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_root,'Project_Neuromodulation-for-Pain','Raw Behavior Data');
                case 'Neurotransmitter Exploration'
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_root,'Project_Neurotransmitter-Exploration','Raw Behavior Data');
                case 'Thalamic-Pupil Synch'
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_root,'Project_Thalamic-Pupil-Synchronization','Raw Behavior Data');
                case 'SSD'
                    % app.SelectProjectDirectoryDropDownLabel.Text = fullfile(params.paths.NECdrive_root,'Project_Neurotransmitter-Exploration','Raw Behavior Data');;
                    app.SelectProjectDirectoryDropDownLabel.Text = 'SSD missing mountpoint';
                    disp('warning: SSD missing mountpoint')
                case 'Browse'
                    path = uigetdir;
                    app.SelectProjectDirectoryDropDownLabel.Text = path;
            end
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            value = app.Switch2.Value;
            switch value
                case 'Outputs'
                    app.VisionPanel.Visible="on";
                    app.NeuropixelsPanel.Visible="on";
                    app.PhotometryPanel.Visible="on";

                    app.OptogeneticsPanel.Visible="off";
                    app.PiezoPanel.Visible="off";
                    app.PharmacologyPanel.Visible="off";
                    
                case 'Inputs'
                    app.VisionPanel.Visible="off";
                    app.NeuropixelsPanel.Visible="off";
                    app.PhotometryPanel.Visible="off";

                    app.OptogeneticsPanel.Visible="on";
                    app.PiezoPanel.Visible="on";
                    app.PharmacologyPanel.Visible="on";
            end
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            % load slrealtime log data
            logData = evalin('base','logsout');
            
            % Retrieve signal traces
            PulseGen1Hz = find(logData,'PulseGen1Hz');
            whiskCam_trig = find(logData,'whiskCam_trig');
            pupilCam_trig = find(logData, 'pupilCam_trig');
            npxls_trig = find(logData, 'npxls_trig');

            % compare timing to 1Hz reference
            cycleTimes = locateCycles(app.sessionParams,PulseGen1Hz.Values.Data);
            % whisker trig stats
            numPupilEdges = countEdges(app.sessionParams, pupilCam_trig.Values.Data, []);
            numPupEdgesByCycle = countEdges(app.sessionParams,pupilCam_trig.Values.Data,cycleTimes);
            meanPupilPerSec = mean(numPupEdgesByCycle);
            stdPupilPerSec = std(numPupEdgesByCycle);
            % pupil trig stats
            numWhiskEdges = countEdges(app.sessionParams, whiskCam_trig.Values.Data, []);
            numWhiskEdgesByCycle = countEdges(app.sessionParams,whiskCam_trig.Values.Data,cycleTimes);
            meanWhiskPerSec = mean(numWhiskEdgesByCycle);
            stdWhiskPerSec = std(numWhiskEdgesByCycle);
            % npxls ref trig stats
            numNpxlsEdges = countEdges(app.sessionParams, npxls_trig.Values.Data, []);
            numNpxlsEdgesByCycle = countEdges(app.sessionParams,whiskCam_trig.Values.Data,cycleTimes);
            meanNpxlsPerSec = mean(numNpxlsEdgesByCycle);
            stdNpxlsPerSec = std(numNpxlsEdgesByCycle);

            % validate pupil and whisker frame counts
            numPupilFrames = countFrames(fullfile(app.sessionParams.paths.tempDir,"Raw Pupil Frames"));
            numWhiskFrames = countFrames(fullfile(app.sessionParams.paths.tempDir,"Raw Whisker Frames"));
            
            % assert validation logic
            pupilFramesOk = (numPupilFrames == numPupilEdges);
            whiskFramesOk = (numWhiskFrames == numWhiskEdges);
            % Pupil valid
            pupilMeanOk = (abs(app.sessionParams.pupilFS - meanPupilPerSec) < app.sessionParams.validationParams.meanThreshold);
            pupilStdOK = (stdPupilPerSec < app.sessionParams.validationParams.stdThreshold);
            pupilStatOk = (pupilMeanOk & pupilStdOK);
            % whisk valid
            whiskMeanOk = (abs(app.sessionParams.whiskFS - meanWhiskPerSec) < app.sessionParams.validationParams.meanThreshold);
            whiskStdOK = (stdWhiskPerSec < app.sessionParams.validationParams.stdThreshold);
            whiskStatOk = (whiskMeanOk & whiskStdOk);
            % Npxls valid
            npxlsMeanOk = (abs(app.sessionParams.npxlsFS - meanNpxlsPerSec) < app.sessionParams.validationParams.meanThreshold);
            npxlsStdOK = (stdNpxlsPerSec < app.sessionParams.validationParams.stdThreshold);
            npxlsStatOk = (npxlsMeanOk & npxlsStdOk);

            sessionOk = (pupilFramesOk & whiskFramesOk & pupilStatOk & whiskStatOk & npxlsStatOK);

            % plot stats on axes


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ExpmntDropDownLabel = uilabel(app.UIFigure);
            app.ExpmntDropDownLabel.Text = 'Expmnt.';
            app.NeuropixelsPanel = uipanel(app.UIFigure);
            app.NeuropixelsPanel.Title = 'Neuropixels';
            app.Switch_2 = uiswitch(app.NeuropixelsPanel, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'On';
            app.RefFSLabel = uilabel(app.NeuropixelsPanel);
            app.RefFSLabel.Text = 'Ref FS';
            app.Npxls_FSEditField = uieditfield(app.NeuropixelsPanel, 'numeric');
            app.Npxls_FSEditField.Value = 250;
            app.Npxls2DropDownLabel = uilabel(app.NeuropixelsPanel);
            app.Npxls2DropDownLabel.Text = 'Npxls 2';
            app.Npxls2DropDown = uidropdown(app.NeuropixelsPanel);
            app.Npxls2DropDown.Value = 'Option 3';
            app.N1LocationLabel = uilabel(app.NeuropixelsPanel);
            app.N1LocationLabel.Text = 'N1 Location';
            app.N2LocationLabel = uilabel(app.NeuropixelsPanel);
            app.N2LocationLabel.Text = 'N2 Location';
            app.Npxls1DropDown_3Label = uilabel(app.NeuropixelsPanel);
            app.Npxls1DropDown_3Label.Text = 'Npxls 1';
            app.Npxls1DropDown_3 = uidropdown(app.NeuropixelsPanel);
            app.Npxls1DropDown_3.Value = 'Option 3';
            app.Switch_5Label = uilabel(app.NeuropixelsPanel);
            app.Switch_5Label.Text = 'Switch';
            app.Switch_5 = uiswitch(app.NeuropixelsPanel, 'slider');
            app.Switch_5.Items = {'Left', 'Right'};
            app.Switch_5.Value = 'Left';
            app.Switch_6Label = uilabel(app.NeuropixelsPanel);
            app.Switch_6Label.Text = 'Switch';
            app.Switch_6 = uiswitch(app.NeuropixelsPanel, 'slider');
            app.Switch_6.Items = {'Left', 'Right'};
            app.Switch_6.Value = 'Left';
            app.LRLabel = uilabel(app.NeuropixelsPanel);
            app.LRLabel.Text = 'L   /   R';
            app.LRLabel_2 = uilabel(app.NeuropixelsPanel);
            app.LRLabel_2.Text = 'L   /   R';
            app.SelectProjectDirectoryDropDownLabel = uilabel(app.UIFigure);
            app.SelectProjectDirectoryDropDownLabel.Text = 'Select Project Directory';
            app.PhotometryPanel = uipanel(app.UIFigure);
            app.PhotometryPanel.Title = 'Photometry';
            app.Switch_3 = uiswitch(app.PhotometryPanel, 'slider');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'On';
            app.Fiber1DropDownLabel = uilabel(app.PhotometryPanel);
            app.Fiber1DropDownLabel.Text = 'Fiber 1';
            app.Fiber1DropDown = uidropdown(app.PhotometryPanel);
            app.Fiber1DropDown.Value = 'Option 3';
            app.FiberLocationLabel = uilabel(app.PhotometryPanel);
            app.FiberLocationLabel.Text = 'Fiber Location';
            app.FluorophoreDropDownLabel = uilabel(app.PhotometryPanel);
            app.FluorophoreDropDownLabel.Text = 'Fluorophore';
            app.FluorophoreDropDown = uidropdown(app.PhotometryPanel);
            app.FluorophoreDropDown.Value = 'Option 3';
            app.WavelengthDropDownLabel = uilabel(app.PhotometryPanel);
            app.WavelengthDropDownLabel.Text = 'Wavelength';
            app.WavelengthDropDown = uidropdown(app.PhotometryPanel);
            app.WavelengthDropDown.Value = 'Option 3';
            app.Switch4Label = uilabel(app.PhotometryPanel);
            app.Switch4Label.Text = 'Switch4';
            app.Switch4 = uiswitch(app.PhotometryPanel, 'slider');
            app.LRLabel_8 = uilabel(app.PhotometryPanel);
            app.LRLabel_8.Text = 'L   /   R';
            app.PharmacologyPanel = uipanel(app.UIFigure);
            app.PharmacologyPanel.Title = 'Pharmacology';
            app.PharmacologyPanel.Visible = 'off';
            app.Switch_14 = uiswitch(app.PharmacologyPanel, 'slider');
            app.Switch_14.Value = 'On';
            app.DrugDropDownLabel = uilabel(app.PharmacologyPanel);
            app.DrugDropDownLabel.Text = 'Drug';
            app.DrugDropDown = uidropdown(app.PharmacologyPanel);
            app.DrugDropDown.Value = 'Option 3';
            app.DosageDropDownLabel = uilabel(app.PharmacologyPanel);
            app.DosageDropDownLabel.Text = 'Dosage';
            app.DosageDropDown = uidropdown(app.PharmacologyPanel);
            app.DosageDropDown.Value = 'Option 3';
            app.TimefromDelivEditFieldLabel = uilabel(app.PharmacologyPanel);
            app.TimefromDelivEditFieldLabel.Text = 'Time from Deliv.';
            app.TimefromDelivEditField = uieditfield(app.PharmacologyPanel, 'numeric');
            app.VisionPanel = uipanel(app.UIFigure);
            app.VisionPanel.Title = 'Vision';
            app.PupilSNLabel_2 = uilabel(app.VisionPanel);
            app.PupilSNLabel_2.Text = 'Pupil S/N';
            app.PupilSNDropDown = uidropdown(app.VisionPanel);
            app.PupilSNDropDown.Value = 'Option 3';
            app.Switch = uiswitch(app.VisionPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'On';
            app.PupilFSEditField_2Label = uilabel(app.VisionPanel);
            app.PupilFSEditField_2Label.Text = 'Pupil FS';
            app.PupilFSEditField = uieditfield(app.VisionPanel, 'numeric');
            app.PupilFSEditField.Value = 10;
            app.WhiskerSNLabel_2 = uilabel(app.VisionPanel);
            app.WhiskerSNLabel_2.Text = 'Whisker S/N';
            app.WhiskerSNDropDown = uidropdown(app.VisionPanel);
            app.WhiskerSNDropDown.Value = 'Option 3';
            app.WhiskFSEditFieldLabel = uilabel(app.VisionPanel);
            app.WhiskFSEditFieldLabel.Text = 'Whisk FS';
            app.WhiskFSEditField = uieditfield(app.VisionPanel, 'numeric');
            app.WhiskFSEditField.Value = 50;
            app.PupilSNLabel = uilabel(app.VisionPanel);
            app.PupilSNLabel.Text = 'Pupil S/N:';
            app.WhiskerSNLabel = uilabel(app.VisionPanel);
            app.WhiskerSNLabel.Text = 'Whisker S/N:';
            app.SpinviewButton = uibutton(app.VisionPanel, 'push');
            app.SpinviewButton.Text = 'Spinview';
            app.SessionTimerminEditFieldLabel = uilabel(app.UIFigure);
            app.SessionTimerminEditFieldLabel.Text = 'Session Timer (min )';
            app.SelectSubjectDropDownLabel = uilabel(app.UIFigure);
            app.SelectSubjectDropDownLabel.Text = 'Select Subject';
            app.SessionMetricsPanel = uipanel(app.UIFigure);
            app.SessionMetricsPanel.Title = 'Session Metrics';
            app.UIAxes_PupilFrames = uiaxes(app.SessionMetricsPanel);
            title(app.UIAxes_PupilFrames, 'Title')
            xlabel(app.UIAxes_PupilFrames, 'X')
            ylabel(app.UIAxes_PupilFrames, 'Y')
            zlabel(app.UIAxes_PupilFrames, 'Z')
            app.UIAxes_PupilFrames.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.UIAxes_PupilFrames.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_WhiskFrames = uiaxes(app.SessionMetricsPanel);
            title(app.UIAxes_WhiskFrames, 'Title')
            xlabel(app.UIAxes_WhiskFrames, 'X')
            ylabel(app.UIAxes_WhiskFrames, 'Y')
            zlabel(app.UIAxes_WhiskFrames, 'Z')
            app.UIAxes_WhiskFrames.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.UIAxes_WhiskFrames.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.TabGroup = uitabgroup(app.SessionMetricsPanel);
            app.PupilTrigTab = uitab(app.TabGroup);
            app.PupilTrigTab.Title = 'Pupil Trig';
            app.UIAxes2 = uiaxes(app.PupilTrigTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Edges / s')
            zlabel(app.UIAxes2, 'Z')
            app.WhiskerTrigTab = uitab(app.TabGroup);
            app.WhiskerTrigTab.Title = 'Whisker Trig';
            app.UIAxes3 = uiaxes(app.WhiskerTrigTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, '# Edges')
            zlabel(app.UIAxes3, 'Z')
            app.NpxlsRefTab = uitab(app.TabGroup);
            app.NpxlsRefTab.Title = 'Npxls Ref';
            app.UIAxes4 = uiaxes(app.NpxlsRefTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, '# Edges')
            zlabel(app.UIAxes4, 'Z')
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.PupilLabel = uilabel(app.SessionMetricsPanel);
            app.PupilLabel.Text = 'Pupil';
            app.WhiskLabel = uilabel(app.SessionMetricsPanel);
            app.WhiskLabel.Text = 'Whisker';
            app.WhiskFrmCount = uilabel(app.SessionMetricsPanel);
            app.WhiskFrmCount.Text = '- -';
            app.PupilFrmCount = uilabel(app.SessionMetricsPanel);
            app.PupilFrmCount.Text = '- -';
            app.ValidateButton = uibutton(app.SessionMetricsPanel, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.HitRate = uilabel(app.SessionMetricsPanel);
            app.HitRate.Text = '-- %';
            app.HitRateLabel = uilabel(app.SessionMetricsPanel);
            app.HitRateLabel.Text = 'Hit Rate';
            app.LRLabel_3 = uilabel(app.UIFigure);
            app.LRLabel_3.Visible = 'off';
            app.LRLabel_3.Text = 'L   /   R';
            app.OptogeneticsPanel = uipanel(app.UIFigure);
            app.OptogeneticsPanel.Title = 'Optogenetics';
            app.OptogeneticsPanel.Visible = 'off';
            app.Switch_10 = uiswitch(app.OptogeneticsPanel, 'slider');
            app.Switch_10.Value = 'On';
            app.Fiber1DropDown_2Label = uilabel(app.OptogeneticsPanel);
            app.Fiber1DropDown_2Label.Text = 'Fiber 1';
            app.Fiber1DropDown_2 = uidropdown(app.OptogeneticsPanel);
            app.Fiber1DropDown_2.Value = 'Option 3';
            app.FiberLocationLabel_2 = uilabel(app.OptogeneticsPanel);
            app.FiberLocationLabel_2.Text = 'Fiber Location';
            app.OpsinDropDownLabel = uilabel(app.OptogeneticsPanel);
            app.OpsinDropDownLabel.Text = 'Opsin';
            app.OpsinDropDown = uidropdown(app.OptogeneticsPanel);
            app.OpsinDropDown.Value = 'Option 3';
            app.StimPatternDropDownLabel = uilabel(app.OptogeneticsPanel);
            app.StimPatternDropDownLabel.Text = 'Stim Pattern';
            app.StimPatternDropDown = uidropdown(app.OptogeneticsPanel);
            app.StimPatternDropDown.Value = 'Option 3';
            app.OpsinLabel = uilabel(app.OptogeneticsPanel);
            app.OpsinLabel.Text = 'Opsin';
            app.WavelengthLabel = uilabel(app.OptogeneticsPanel);
            app.WavelengthLabel.Text = 'Wavelength';
            app.StimPatternLabel = uilabel(app.OptogeneticsPanel);
            app.StimPatternLabel.Text = 'Stim Pattern';
            app.WavelengthDropDown_6Label = uilabel(app.OptogeneticsPanel);
            app.WavelengthDropDown_6Label.Text = 'Wavelength';
            app.WavelengthDropDown_6 = uidropdown(app.OptogeneticsPanel);
            app.WavelengthDropDown_6.Value = 'Option 3';
            app.Switch3 = uiswitch(app.OptogeneticsPanel, 'slider');
            app.Switch3.Items = {'L', 'R'};
            app.Switch3.Value = 'L';
            app.LRLabel_7 = uilabel(app.OptogeneticsPanel);
            app.LRLabel_7.Text = 'L   /   R';
            app.Switch2Label = uilabel(app.UIFigure);
            app.Switch2Label.Text = 'Switch2';
            app.PiezoPanel = uipanel(app.UIFigure);
            app.PiezoPanel.Title = 'Piezo';
            app.PiezoPanel.Visible = 'off';
            app.Switch_15 = uiswitch(app.PiezoPanel, 'slider');
            app.Switch_15.Value = 'On';
            app.DataStreamingModalitiesLabel = uilabel(app.UIFigure);
            app.DataStreamingModalitiesLabel.Text = 'Data Streaming / Modalities';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SelectProjectDirectoryDropDown = uidropdown(app.UIFigure);
            app.SelectProjectDirectoryDropDown.Items = {'Neuromodulation for Pain', 'Neurotransmitter Exploration', 'Thalamic-Pupil Synch', 'SSD', 'Browse'};
            app.SelectProjectDirectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectProjectDirectoryDropDownValueChanged, true);
            app.SelectProjectDirectoryDropDown.Value = 'Neuromodulation for Pain';
            app.LoggingLabel = uilabel(app.UIFigure);
            app.LoggingLabel.Text = 'Logging';
            app.connectTarget = uibutton(app.UIFigure, 'state');
            app.connectTarget.ValueChangedFcn = createCallbackFcn(app, @connectTargetValueChanged, true);
            app.connectTarget.Icon = 'slrtDisconnectIcon.png';
            app.connectTarget.Text = 'Button';
            app.SessionTimerminEditField = uieditfield(app.UIFigure, 'numeric');
            app.SessionTimerminEditField.Value = 30;
            app.SelectSubjectDropDown = uidropdown(app.UIFigure);
            app.SelectSubjectDropDown.Items = {'Neuromodulation for Pain', 'Neurotransmitter Exploration', 'Thalamic-Pupil Synch', 'SSD', 'Browse'};
            app.SelectSubjectDropDown.Value = 'Browse';
            app.StatusBar = slrealtime.ui.control.StatusBar(app.UIFigure);
            app.Switch2 = uiswitch(app.UIFigure, 'slider');
            app.Switch2.Items = {'Outputs', 'Inputs'};
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch2.Value = 'Outputs';
            app.ExpmntDropDown = uidropdown(app.UIFigure);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'slrtStopIcon.png';
            app.StopButton.Text = '';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'slrtRunIcon.png';
            app.StartButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nCORTEx_console

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end